classdef BrainWaves < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BrainWavesUIFigure         matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        OpenMenu                   matlab.ui.container.Menu
        SaveMenu                   matlab.ui.container.Menu
        SaveAsMenu                 matlab.ui.container.Menu
        ImportMenu                 matlab.ui.container.Menu
        ImportLFPMenu              matlab.ui.container.Menu
        ImportBatchMenu            matlab.ui.container.Menu
        ExportMenu                 matlab.ui.container.Menu
        ExportPlotDataMenu         matlab.ui.container.Menu
        AnalysisMonitorPanel       matlab.ui.container.Panel
        MonitorGridLayout          matlab.ui.container.GridLayout
        AnalysisMonitorGridLayout  matlab.ui.container.GridLayout
        SNRdBEditField             matlab.ui.control.NumericEditField
        SNRdBEditFieldLabel        matlab.ui.control.Label
        AnalyzingFsEditField       matlab.ui.control.NumericEditField
        AnalyzingFsEditFieldLabel  matlab.ui.control.Label
        ChorNEditField             matlab.ui.control.NumericEditField
        ChorNLabel                 matlab.ui.control.Label
        BatchPanel                 matlab.ui.container.Panel
        BatchTreeGridLayout        matlab.ui.container.GridLayout
        BatchTree                  matlab.ui.container.CheckBoxTree
        CenterPanel                matlab.ui.container.Panel
        CentralGridLayout          matlab.ui.container.GridLayout
        GraphTabGroup              matlab.ui.container.TabGroup
        WelcomeTab                 matlab.ui.container.Tab
        WelcomeGridLayout          matlab.ui.container.GridLayout
        Image                      matlab.ui.control.Image
        LeftPanel                  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        RunAnalysisButton          matlab.ui.control.Button
        SelectAnlysisDropDown      matlab.ui.control.DropDown
        DataLoadedLamp             matlab.ui.control.Lamp
        DataLoadedLampLabel        matlab.ui.control.Label
        PathEditField              matlab.ui.control.EditField
        GraphTabContextMenu        matlab.ui.container.ContextMenu
        DeleteMenu                 matlab.ui.container.Menu
    end

    
    properties (Access = private)
        Property % Description

        DialogAppShowLFP % Dialog window for Show LFP
        DialogAppPowerSpectrum % Dialog window for Power Spectrum

        MODE % Batch type: SINGLE or BATCH
        LFP % Timeseries LFP data
        BATCH_LFP % Batch of LFPs

        SELECTED_NODES % Checked batch tree nodes

        PLOTDATA % Data in current plot
        PLOTTAB % Tab of plots
        I_CURRENT_PLOT % Index of current plot
    end
    
    methods (Access = public)
        function showLFP(app, lfp, ch, duration, ds_TF, ds_frequency, norm_TF, norm_method, legend_info)
            % Disable button during run function
            app.switchStatus('off');

            % Reset data
            app.PLOTDATA = struct;

            % Reset plot tab
            n_new_tab = app.makeNewCenterTab('new');

            % Get target uiaxes
            plot_here = app.PLOTTAB(n_new_tab).CONTENTS;

            % Get sample index by channel
            for i = 1:numel(lfp)
                if isequal(lfp(i).channel, ch)
                    channel_idx = i;
                end
            end

            % variables
            fs = lfp(channel_idx).fs;

            % get samples
            samples = lfp(channel_idx).lfp;
            ts = lfp(channel_idx).ts;

            % downsample
            if isequal(norm_TF, 'On')
                [fs, samples] = dsLFP(samples, fs, ds_frequency);
                [~, ts] = dsLFP(ts, fs, ds_frequency);
            end

            % normalize
            if isequal(ds_TF, 'On')
                [samples, ~] = normLFP(samples, norm_method);
            end

            % get index
            start_idx = duration(1)*fs;
            if start_idx == 0
                start_idx = 1;
            end
            end_idx = duration(end)*fs;

            % get samples of interests
            samples = samples(start_idx:end_idx);
            ts = ts(start_idx:end_idx);

            % Calculate SNR (dB)
            SNRdB = app.calcSNR(samples, fs);
           
            % Update plot
            plot(plot_here, ts, samples);
            legend(plot_here, strcat("channel [", string(legend_info), "]"));
            xlabel(plot_here, 'Time (s)');
            ylabel(plot_here, 'Amplitude (μV)');

            % Update Analysis Monitor
            app.updateAnalysisMonitor(ch, fs, SNRdB);

            % Storate data
            app.PLOTDATA.PLOT_TYPE = 'SHOW LFP';
            app.PLOTDATA.PATH = app.PathEditField.Value;
            app.PLOTDATA.CHANNEL = ch;
            if isequal(norm_TF, 'On')
                app.PLOTDATA.NORM_METHOD = norm_method;
            end
            if isequal(norm_TF, 'Off')
                app.PLOTDATA.NORM_METHOD = 'NaN';
            end
            app.PLOTDATA.FS = fs;
            app.PLOTDATA.SAMPLES = samples;
            app.PLOTDATA.TS = ts;
            app.PLOTDATA.SNRdB = SNRdB;
            
            % Update plot data in tab
            app.updataPlotData(n_new_tab);
       
            % Re-enable the Run analysis button
            app.switchStatus('on');
        end

        function waveletPowerSpectrum(app, data, ch, ds_frequency, norm_method, mode, legend_info)
            % Disable button during run function
            app.switchStatus('off');

            % Reset variables
            app.PLOTDATA = struct;

            % Reset plot tab
            n_new_tab = app.makeNewCenterTab('new');

            % Get target uiaxes
            plot_here = app.PLOTTAB(n_new_tab).CONTENTS;
            
            % For single LFP
            if isequal(mode, 'SINGLE')

                % get sampling frequency
                fs = data(ch).fs;
    
                % get samples
                samples = data(ch).lfp;
    
                % preprocessing: downsample, normalize, calculate_snr
                [samples, downsampled_fs, SNRdB] = app.preprocessing(samples, fs, ds_frequency, norm_method);
    
                % compute wavelet transform
                [wave, freq] = cwt(samples, downsampled_fs);
                f_power = mean((abs(wave).^2), 2);
    
                % Update plot
                plot(plot_here, freq, f_power);
                legend(plot_here, strcat("channel [", string(legend_info), "]"));
                xlabel(plot_here, 'Frequency (Hz)');
                ylabel(plot_here, 'Normalized power (A.U.)');
    
                % Update Analysis Monitor
                app.updateAnalysisMonitor(ch, downsampled_fs, SNRdB);

                % Storage data
                app.PLOTDATA.PLOT_TYPE = 'POWER SPECTRUM (SINGLE)';
                app.PLOTDATA.PATH = app.PathEditField.Value;
                app.PLOTDATA.CHANNEL = ch;
                app.PLOTDATA.NORM_METHOD = norm_method;
                app.PLOTDATA.FS = fs;
                app.PLOTDATA.F_POWER = f_power;
                app.PLOTDATA.FREQUENCY = freq;
                app.PLOTDATA.SNRdB = SNRdB;

                % Update plot data in tab
                app.updataPlotData(n_new_tab);
            end

            % For batch LFP
            if isequal(mode, 'BATCH')

                % Variables
                f_powers = [];
                frequency = [];
                SNRs = [];
                
                % Open waitber
                progress = 0;
                total_progress = 0;
                for batch = 1:numel(data)
                    total_progress = total_progress + numel(data(batch).LFP);
                end
                p = waitbar(progress, 'Wavelet transform ...');

                for batch = 1:numel(data)
                    if ~isempty(data(batch)) % For not empty batch
                        for ch = 1:numel(data(batch).LFP)
                            % get sampling frequency
                            fs = data(batch).LFP(ch).fs;
                
                            % get samples
                            samples = data(batch).LFP.lfp;
    
                            % preprocessing: downsample, normalize, calculate_snr
                            [samples, downsampled_fs, SNRdB] = app.preprocessing(samples, fs, ds_frequency, norm_method);
    
                            % compute wavelet transform
                            [wave, freq] = cwt(samples, downsampled_fs);
                            f_power = mean((abs(wave).^2), 2); % N x 1 vector
                            
                            % Append NaN unitl length is 16000
                            while length(f_power) < 16000
                                f_power = [f_power; NaN];
                            end
    
                            % Save wavelet results
                            f_powers = [f_powers, f_power]; % 1D = f_power, 2D = Entity
                            if length(frequency) < length(freq)
                                frequency = freq; % N x 1 vector
                            end
                            SNRs = [SNRs; SNRdB]; % N x 1 vector
    
                            % Update waitbar
                            progress = progress + 1;
                            waitbar(progress/total_progress, p, 'Wavelet transform ...');
                        end
                    end
                    if isempty(data(batch)) % Pass empty batch
                        continue;
                    end
                end

                % Calculate mean ± SEM by entitiy
                [sem, m] = app.sem(f_powers, 2); % SEM and Mean
                mean_snr = mean(SNRs, 1); % Mean of SNRs
    
                % Update plot
                sem_top = m(1:length(frequency)) + sem(1:length(frequency)); % Top of SEM
                sem_bottom = m(1:length(frequency)) - sem(1:length(frequency)); % Bottom of SEM
                frequency_between = [frequency; flipud(frequency)]; % Between X
                inBetween = [sem_top; flipud(sem_bottom)]; % Between Y

                hold(plot_here, 'on') % Hold on
                plot(plot_here, frequency, sem_top, 'LineStyle', 'none'); % Plot top of SEM
                plot(plot_here, frequency, sem_bottom, 'LineStyle', 'none'); % Plot bottom of SEM
                fill(plot_here, frequency_between, inBetween, [0, 0.4470, 0.7410], 'FaceAlpha', 0.3, 'LineStyle', 'none'); % Fill between SEM
                plot(plot_here, frequency, m(1:length(frequency)), 'Color', 'black'); % Plot mean
                legend(plot_here, '', '', '', strcat("channel [", legend_info, "]"));
                hold(plot_here, 'off') % Hold off

                xlabel(plot_here, 'Frequency (Hz)');
                ylabel(plot_here, 'Normalized power (A.U.)');
    
                % Update Analysis Monitor
                app.updateAnalysisMonitor(total_progress, downsampled_fs, mean_snr); % Update monitor

                % Storage data
                app.PLOTDATA.PLOT_TYPE = 'POWER SPECTRUM (BATCH)';
                app.PLOTDATA.PATH = app.PathEditField.Value;
                app.PLOTDATA.CHANNEL = 'Batch';
                app.PLOTDATA.BATCH = app.extractBatchNames(app.SELECTED_NODES);
                app.PLOTDATA.NORM_METHOD = norm_method;
                app.PLOTDATA.FS = fs;
                app.PLOTDATA.F_POWER = f_powers;
                app.PLOTDATA.FREQUENCY = frequency;
                app.PLOTDATA.SEM = sem;
                app.PLOTDATA.MEAN = m;
                app.PLOTDATA.SNRdB = mean_snr;

                % Update plot data in tab
                app.updataPlotData(n_new_tab);

                % Close waitbar
                close(p);
            end

            % Re-enable the Run analysis button
            app.switchStatus('on');
        end
        
        function SNRdB = calcSNR(~, samples, fs)
            SNRdB = snr(samples, fs);
        end
        
        function callBatchLFP(app, batch_path_list)
            % Disable button
            app.switchStatus('off');

            % Reset batch
            app.LFP = struct;
            app.BATCH_LFP = struct;
            
            % Reset batch tree
            TreeChildren = app.BatchTree.Children;
            TreeChildren.delete;

            % Reset monitor
            app.updateAnalysisMonitor(0, 0, 0);

            % Open waitbar
            p = waitbar(0, 'Loading batch data ...');

            % Import LFP by path
            batch_path_list_index = 1;
            for i = 1:numel(batch_path_list)
                % Empty field exception
                if isempty(batch_path_list(i).path)
                    continue;
                end
                
                if ~isempty(batch_path_list(i).path)
                    lfp = BW_callLFP(batch_path_list(i).path); % call LFP
                    
                    % Save results
                    app.BATCH_LFP(batch_path_list_index).index = batch_path_list(i).index;
                    app.BATCH_LFP(batch_path_list_index).batch_name = batch_path_list(i).batch_name;
                    app.BATCH_LFP(batch_path_list_index).path = batch_path_list(i).path;
                    app.BATCH_LFP(batch_path_list_index).LFP = lfp;    

                    % Increase index
                    batch_path_list_index = batch_path_list_index + 1;
                end

                % Update waitbar
                waitbar(i/length(batch_path_list), p, 'Loading batch data ...');
            end
            
            % Close waitbar
            close(p);

            % Update path edit field for batch import
            app.PathEditField.Value = 'Batch imported';

            % Update Data loaded lamp
            if isempty(app.BATCH_LFP) % Empty batch_lfp exception
                app.DataLoadedLamp.Color = 'red';
            end

            if ~isempty(app.BATCH_LFP) 
                app.DataLoadedLamp.Color = 'green';
            end

            % Update batch tree
            app.updateBatchTree(app.BATCH_LFP)
            
            % Enable button
            app.switchStatus('on');
        end
        
        function updateBatchTree(app, batch_lfp)
            % Empty batch_lfp exception
            if isempty(batch_lfp)
                uialert(app.BrainWavesUIFigure, 'Cannot update batch tree, Empty data', 'Data Handling Error');
            end
            
            % Update batch_path_list
            if ~isempty(batch_lfp)
                for i = 1:numel(batch_lfp)
                    % Get batch name
                    batch_name = batch_lfp(i).batch_name;
                    
                    % Make parent node by path
                    parent = uitreenode(app.BatchTree, 'Text', batch_name);

                    % Make children nodes by channel
                    for ch = 1:numel(batch_lfp(i).LFP)
                        uitreenode(parent, 'Text', batch_lfp(i).LFP(ch).name);
                    end
                end
            end
        end

        function selected_nodes = getCheckedLFP(~, batch_lfp, checked_nodes)
            % LOGIC HINT: 
            % The order of checked_nodes is same with the order of
            % batch. If it moves to next batch, it needs to reset channel
            % index.

            % Reset variables
            selected_nodes = struct;

            % Reset batch index
            batch_index = 1;

            % Reset channel index
            channel_index = 1;
            
            % Get batch names to check it is parent node
            batch_names = [];
            for batch = 1:numel(batch_lfp)
                batch_names = [batch_names, string(batch_lfp(batch).batch_name)];
            end

            % Get checked LFP
            for batch = 1:numel(batch_lfp)
                for node = 1:numel(checked_nodes) % For each nodes
                    checked_element = checked_nodes(node).Text; % Get checked node name
                    if ~ismember(checked_element, batch_names)
                        checked_batch_name = checked_nodes(node).Parent.Text; % Get checked parent node name
                    end

                    if ismember(checked_element, batch_names) % Exception for parent node are checked
                        continue; % Go to next node
                    end
                    
                    if isequal(batch_lfp(batch).batch_name, checked_batch_name) % Compare batch_name
                        for i = 1:numel(batch_lfp(batch).LFP)
                            if isequal(batch_lfp(batch).LFP(i).name, checked_element) % Compare element (; channel) name
                                % Save data
                                selected_nodes(batch_index).index = batch_lfp(batch).index;
                                selected_nodes(batch_index).batch_name = batch_lfp(batch).batch_name;
                                selected_nodes(batch_index).path = batch_lfp(batch).path;
                                selected_nodes(batch_index).LFP(channel_index) = batch_lfp(batch).LFP(i);
    
                                % Increase index
                                channel_index = channel_index + 1;
                            end

                            if ~isequal(batch_lfp(batch).LFP(i).name, checked_element) % Compare element (; channel) name
                                continue; % Go to next channel
                            end
                        end
                    end
                    if ~isequal(batch_lfp(batch).batch_name, checked_batch_name) % Compare batch_name
                        continue; % Go to next node
                    end
                end
                batch_index = batch_index + 1; % Next batch
                channel_index = 1; % Reset channel index for next batch
            end
        end
        
        function [sem, m] = sem(~, data, avg_dim) % 1D = Serial data, 2D = Entitiy
            % Dimension selector
            if avg_dim == 1
                data = data';
            end
            if avg_dim == 2
                data = data;
            end

            % Mean
            m = mean(data, 2, 'omitnan');

            % SEM
            sem = [];
            for i = 1:length(data)
                % Set target data to calculate SEM
                target_data = data(i, :);
                
                % Standard error mean
                sem = [sem; std(target_data, 'omitmissing')/(length(target_data(~isnan(target_data))))];
            end
        end        
        
        function updateAnalysisMonitor(app, ch, downsampled_fs, SNRdB)
            app.ChorNEditField.Value = ch;
            app.AnalyzingFsEditField.Value = downsampled_fs;
            app.SNRdBEditField.Value = SNRdB;
        end
        
        function [samples, downsampled_fs, SNRdB] = preprocessing(app, samples, fs, ds_frequency, norm_method)
            % downsample
            [downsampled_fs, samples] = dsLFP(samples, fs, ds_frequency);

            % normalize
            [samples, ~] = normLFP(samples, norm_method);

            % Calculate SNR (dB)
            SNRdB = app.calcSNR(samples, downsampled_fs);
        end
        
        function switchStatus(app, on_or_off)
            % Change status
            app.FileMenu.Enable = on_or_off; % File menu
            app.OpenMenu.Enable = on_or_off; % open menu
            app.SaveMenu.Enable = on_or_off; % Save menu
            app.SaveAsMenu.Enable = on_or_off; % Save As menu
            app.ImportMenu.Enable = on_or_off; % Import menu
            app.ImportLFPMenu.Enable = on_or_off; % Import LFP menu
            app.ImportBatchMenu.Enable = on_or_off; % Import batch LFP menu
            app.ExportMenu.Enable = on_or_off; % Export menu
            app.ExportPlotDataMenu.Enable = on_or_off; % Explot plot data menu
            app.SelectAnlysisDropDown.Enable = on_or_off; % Analysis selection drop down
            app.RunAnalysisButton.Enable = on_or_off; % Run analysis button
            app.BatchPanel.Enable = on_or_off; % Batch tree panel
        end

        function startupSwitchStatus(app, on_or_off)
            % Change status
            app.SaveMenu.Enable = on_or_off; % Save menu
            app.SaveAsMenu.Enable = on_or_off; % Save As menu
            app.ExportMenu.Enable = on_or_off; % Export menu
            app.ExportPlotDataMenu.Enable = on_or_off; % Explot plot data menu
            app.SelectAnlysisDropDown.Enable = on_or_off; % Analysis selection drop down
            app.RunAnalysisButton.Enable = on_or_off; % Run analysis button
            app.BatchPanel.Enable = on_or_off; % Batch tree panel
        end
        
        function batch_items = extractBatchNames(~, selected_nodes)
            
            % Reset variables
            batch_items = [];

            for batch = 1:numel(selected_nodes)
                for i = 1:numel(selected_nodes(batch).LFP)
                    batch_items = [batch_items, string(strcat(selected_nodes(batch).batch_name, ' / ', selected_nodes(batch).LFP(i).name))];
                end
            end
        end
        
        function n_new_tab = makeNewCenterTab(app, mode)
            % Set start tab
            if isequal(mode, 'startup')
                % Reset plot tabs
                app.PLOTTAB = struct;
                delete(app.GraphTabGroup.Children); % Delete children tabs

                n_new_tab = 1; % Count first tab number
                app.PLOTTAB(n_new_tab).INDEX = n_new_tab; % Set plot index
                app.PLOTTAB(n_new_tab).TAB_TITLE = 'Welcome'; % Set tab title
                app.PLOTTAB(n_new_tab).TAB = uitab(app.GraphTabGroup, 'Title', app.PLOTTAB(n_new_tab).TAB_TITLE); % Make tab
                app.PLOTTAB(n_new_tab).TAB.ContextMenu = app.GraphTabContextMenu; % Apply context menu for each tab
                app.PLOTTAB(n_new_tab).GRID_LAYOUT = uigridlayout(app.PLOTTAB(n_new_tab).TAB); % Add grid layout
    
                % Tune grid layout
                app.PLOTTAB(n_new_tab).GRID_LAYOUT.ColumnWidth = {'1x'};
                app.PLOTTAB(n_new_tab).GRID_LAYOUT.RowHeight = {'1x'};
                app.PLOTTAB(n_new_tab).GRID_LAYOUT.ColumnSpacing = 10;
                app.PLOTTAB(n_new_tab).GRID_LAYOUT.RowSpacing = 10;
                app.PLOTTAB(n_new_tab).GRID_LAYOUT.Padding = [10, 10, 10, 10];

                img_source = fullfile('C:\Program Files\SNU\BrainWaves\application', 'logo_square_small.svg');
                app.PLOTTAB(n_new_tab).CONTENTS = uiimage(app.PLOTTAB(n_new_tab).GRID_LAYOUT, 'ImageSource', img_source); % Add image
                app.PLOTTAB(n_new_tab).PLOTDATA = [];
                app.I_CURRENT_PLOT = n_new_tab; % Update current plot number
            end

            % Set new tab
            if isequal(mode, 'new')
                n_new_tab = max([app.PLOTTAB.INDEX]) + 1; % Count next tab number

                % Make plot tab
                app.PLOTTAB(n_new_tab).INDEX = n_new_tab; % Set plot index
                app.PLOTTAB(n_new_tab).TAB_TITLE = string(strcat('Plot_', num2str(n_new_tab))); % Set tab title
                app.PLOTTAB(n_new_tab).TAB = uitab(app.GraphTabGroup, 'Title', app.PLOTTAB(n_new_tab).TAB_TITLE); % Make tab   
                app.PLOTTAB(n_new_tab).TAB.ContextMenu = app.GraphTabContextMenu; % Apply context menu for each tab
                app.PLOTTAB(n_new_tab).GRID_LAYOUT = uigridlayout(app.PLOTTAB(n_new_tab).TAB); % Add grid layout
    
                % Tune grid layout
                app.PLOTTAB(n_new_tab).GRID_LAYOUT.ColumnWidth = {'1x'};
                app.PLOTTAB(n_new_tab).GRID_LAYOUT.RowHeight = {'1x'};
                app.PLOTTAB(n_new_tab).GRID_LAYOUT.ColumnSpacing = 10;
                app.PLOTTAB(n_new_tab).GRID_LAYOUT.RowSpacing = 10;
                app.PLOTTAB(n_new_tab).GRID_LAYOUT.Padding = [10, 10, 10, 10];
                
                app.PLOTTAB(n_new_tab).CONTENTS = uiaxes(app.PLOTTAB(n_new_tab).GRID_LAYOUT); % Add axes
    
                app.PLOTTAB(n_new_tab).PLOTDATA = app.PLOTDATA; % Set plot data
    
                app.GraphTabGroup.SelectedTab = app.PLOTTAB(n_new_tab).TAB; % Update focusing to new tab
                app.I_CURRENT_PLOT = n_new_tab; % Update current plot number
            end
        end
        
        function updataPlotData(app, n_tab)
            app.PLOTTAB(n_tab).PLOTDATA = app.PLOTDATA; % Update plot data
        end
        
        function updateCurrentPlotIndex(app)
            selectedTab = app.GraphTabGroup.SelectedTab;
            
            if ~isempty(selectedTab)
                title = selectedTab.Title; % Get current opended tab title
                for i = 1:numel(app.PLOTTAB)
                    if isequal(app.PLOTTAB(i).TAB_TITLE, title)
                        app.I_CURRENT_PLOT = app.PLOTTAB(i).INDEX; % Update current plot number
                    end
                end
            end

            if isempty(selectedTab)
                app.I_CURRENT_PLOT = 1; % Reset plot index
            end
        end
        
        function updateSavedPlotTabs(app)
            % Reset index
            delete_index = [];
            
            % Empty plot exception
            if isempty([app.PLOTTAB.TAB])
            end

            % Update saved plot tabs
            if ~isempty([app.PLOTTAB.TAB])
                for tab = 2:numel(app.PLOTTAB) % Exclude welcome tab
                    % Remove tab if removed before
                    if isempty(app.PLOTTAB(tab).TAB)
                        delete_index = [delete_index, tab]; % Save index to remove
                        continue;
                    end

                    child_tab = uitab(app.GraphTabGroup, 'Title', app.PLOTTAB(tab).TAB_TITLE); % Make new tab
                    child_tab.ContextMenu = app.GraphTabContextMenu; % Apply context menu for each tab
                    app.PLOTTAB(tab).TAB.Parent = child_tab; % Update parent of tabs
                    app.PLOTTAB(tab).TAB = child_tab; % Replace tab object
                end
            end

            % Remove blank or removed plots
            app.PLOTTAB(delete_index) = [];
        end
        
        function saved_plot_tab = prepareSavingPlotTab(~, plot_tab)
            % Reset index
            delete_index = 1;

            for tab = 2:numel(plot_tab) % Exclude welcom tab
                if ~isempty(plot_tab(tab).TAB)
                    plot_tab(tab).TAB = plot_tab(tab).TAB.Children; % Replace tab object to object
                end

                if isempty(plot_tab(tab).TAB)
                    delete_index = [delete_index, tab]; % Save index to remove
                end
            end
            
            % Remove blank or removed plots
            plot_tab(delete_index) = []; 

            % Return result
            saved_plot_tab = plot_tab;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Disable buttons when session started
            app.startupSwitchStatus('off');

            % Start up tab
            app.makeNewCenterTab('startup');
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            % Select file
            [file, path] = uigetfile('*.mat');

            if isequal(file, 0) % File unselected
                % Disable switches
                app.startupSwitchStatus('off');
            end
            
            if ~isequal(file, 0) % File selected
                % Disable buttons
                app.switchStatus('off');

                % Reset to start up tab
                app.makeNewCenterTab('startup');

                % load file
                LoadedFile = load(fullfile(path, file)); % Load files
                app.MODE = LoadedFile.SavedMODE; % Update mode
                app.PLOTTAB = [app.PLOTTAB, LoadedFile.SavedPLOTTAB]; % Add saved plot tabs

                % Open single LFP
                if isequal(app.MODE, 'SINGLE')

                    % Reset variables
                    app.BATCH_LFP = struct;

                    % Reset batch tree
                    TreeChildren = app.BatchTree.Children;
                    TreeChildren.delete;

                    % Reset monitor
                    app.updateAnalysisMonitor(0, 0, 0);

                    % Load LFP
                    app.LFP = LoadedFile.SavedLFP;

                    % Update Data loaded lamp
                    if isempty(app.LFP) % Empty batch_lfp exception
                        app.DataLoadedLamp.Color = 'red';
                    end
        
                    if ~isempty(app.LFP) 
                        app.DataLoadedLamp.Color = 'green';
                    end

                    % Update path field
                    app.PathEditField.Value = fullfile(path, file);

                    % Update saved plots
                    app.updateSavedPlotTabs();

                    % Enable buttons
                    app.switchStatus('on');

                    % Alert open
                    uialert(app.BrainWavesUIFigure, 'Open succeed', 'Notice', 'Icon', 'success');
                end             

                % Open batch LFP
                if isequal(app.MODE, 'BATCH')

                    % Reset variables
                    app.LFP = struct;

                    % Reset batch tree
                    TreeChildren = app.BatchTree.Children;
                    TreeChildren.delete;

                    % Reset monitor
                    app.updateAnalysisMonitor(0, 0, 0);

                    % Load Batch LFP
                    app.BATCH_LFP = LoadedFile.SavedBATCH_LFP;

                    % Update path edit field for batch import
                    app.PathEditField.Value = 'Batch imported';
        
                    % Update Data loaded lamp
                    if isempty(app.BATCH_LFP) % Empty batch_lfp exception
                        app.DataLoadedLamp.Color = 'red';
                    end
        
                    if ~isempty(app.BATCH_LFP) 
                        app.DataLoadedLamp.Color = 'green';
                    end
        
                    % Update batch tree
                    app.updateBatchTree(app.BATCH_LFP);

                    % Update saved plots
                    app.updateSavedPlotTabs();

                    % Enable buttons
                    app.switchStatus('on');
                    
                    % Alert open
                    uialert(app.BrainWavesUIFigure, 'Open succeed', 'Notice', 'Icon', 'success');
                end                
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            path = app.PathEditField.Value;
            
            % Cannot save exception
            if isequal(path, 0)
                uialert(app.BrainWavesUIFigure, 'Save Failed', 'Path Error');
            end

            % Save data
            if ~isequal(path, 0)
                try
                    % Save for single LFP
                    if isequal(app.MODE, 'SINGLE')
                        SavedLFP = app.LFP;
                        SavedPLOTTAB = app.prepareSavingPlotTab(app.PLOTTAB);
                        SavedMODE = app.MODE;
                        save(fullfile(path, strcat(string(datetime('today')), '_Results.mat')), 'SavedLFP', 'SavedPLOTTAB', 'SavedMODE');
                        uialert(app.BrainWavesUIFigure, 'Save succeed', 'Notice', 'Icon', 'success');
                    end
        
                    % Save for batch LFP
                    if isequal(app.MODE, 'BATCH')
                        SavedBATCH_LFP = app.BATCH_LFP;
                        SavedPLOTTAB = app.prepareSavingPlotTab(app.PLOTTAB);
                        SavedMODE = app.MODE;
                        save(fullfile(path, strcat(string(datetime('today')), '_Results.mat')), 'SavedBATCH_LFP', 'SavedPLOTTAB', 'SavedMODE');
                        uialert(app.BrainWavesUIFigure, 'Save succeed', 'Notice', 'Icon', 'success');
                    end
                catch
                    uialert(app.BrainWavesUIFigure, 'Save Failed', 'Data Handling Error');
                end
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            % Save As file
            filter = {'*.mat'};
            title = 'Save results';
            defname = strcat(string(datetime('today')), '_Result.mat');
            [file, path] = uiputfile(filter, title, defname);
            
            % Canceled exception
            if isequal(file, 0)
            end

            % Path selected
            if ~isequal(file, 0)
                try
                    % Save for single LFP
                    if isequal(app.MODE, 'SINGLE')
                        SavedLFP = app.LFP;
                        SavedPLOTTAB = app.prepareSavingPlotTab(app.PLOTTAB);
                        SavedMODE = app.MODE;
                        save(fullfile(path, file), 'SavedLFP', 'SavedPLOTTAB', 'SavedMODE');
                        uialert(app.BrainWavesUIFigure, 'Save succeed', 'Notice', 'Icon', 'success');
                    end
        
                    % Save for batch LFP
                    if isequal(app.MODE, 'BATCH')
                        SavedBATCH_LFP = app.BATCH_LFP;
                        SavedPLOTTAB = app.prepareSavingPlotTab(app.PLOTTAB);
                        SavedMODE = app.MODE;
                        save(fullfile(path, file), 'SavedBATCH_LFP', 'SavedPLOTTAB', 'SavedMODE');
                        uialert(app.BrainWavesUIFigure, 'Save succeed', 'Notice', 'Icon', 'success');
                    end
                catch
                    uialert(app.BrainWavesUIFigure, 'Save Failed', 'Data Handling Error');
                end
            end
        end

        % Menu selected function: ImportLFPMenu
        function ImportLFPMenuSelected(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isequal(path, 0)
                app.PathEditField.Value = path;
                app.PathEditFieldValueChanged;
            end
        end

        % Menu selected function: ImportBatchMenu
        function ImportBatchMenuSelected(app, event)
            % Disable button while dialog is open
            app.switchStatus('off');
            
            % Import batch LFP
            app.DialogAppShowLFP = DialogBatchSelector(app);

            % Change mode
            app.MODE = 'BATCH';
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            
            % Disable button
            app.switchStatus('off');
            
            path = app.PathEditField.Value;
            
            if ~isfolder(path)
                app.PathEditField.Value = event.PreviousValue;
                uialert(app.BrainWavesUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                
                % Reset batch
                app.LFP = struct;
                app.BATCH_LFP = struct;

                % Reset monitor
                app.updateAnalysisMonitor(0, 0, 0);

                try
                    % Change mode
                    app.MODE = 'SINGLE';

                    % Import single LFP
                    app.LFP = BW_callLFP(path);
                    
                    % Change data loaded lamp
                    app.DataLoadedLamp.Color = 'green';
                catch
                    uialert(app.BrainWavesUIFigure, 'Cannot import LFP', 'Data Hadling Error');
                    app.DataLoadedLamp.Color = 'red';
                end
            end
            
            % Enable button
            app.switchStatus('on');
        end

        % Menu selected function: ExportPlotDataMenu
        function ExportPlotDataMenuSelected(app, event)
            % Save As file
            filter = {'*.mat'};
            title = 'Save plot data';
            defname = strcat(string(datetime('today')), '_Plot_Data.mat');
            [file, path] = uiputfile(filter, title, defname);

            % Cancel export exception
            if isequal(file, 0)
                uialert(app.BrainWavesUIFigure, 'Export Plot Data Canceled', 'Warning');
            end

            % Path selection error exception
            if ~isequal(file, 0) && ~isfolder(string(path))
                uialert(app.BrainWavesUIFigure, 'Cannot Export Plot Data', 'Path Error');
            end
            
            if ~isequal(file, 0) && isfolder(path)
                % Save for current plot data
                SavedPlotData = app.PLOTTAB;
                save(fullfile(path, file), 'SavedPlotData');
                uialert(app.BrainWavesUIFigure, 'Save succeed', 'Notice', 'Icon', 'success');
            end
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            AnalysisMode = app.SelectAnlysisDropDown.Value;

            % Disable button while dialog is open
            app.switchStatus('off');

            if isequal(AnalysisMode, 'Show LFP')
                % Single LFP data loaded
                if isequal(app.MODE, 'SINGLE')
                    
                    % Exceptions during running
                    if isempty(app.LFP) % No data exception
                        uialert(app.BrainWavesUIFigure, 'Nothing in LFP', 'Data Handling Error');
                    end
                    
                    % Run function
                    if ~isempty(app.LFP)
                        try
                            % Show LFP
                            app.DialogAppShowLFP = DialogShowLFP(app, app.LFP, app.MODE);
                        catch
                            % Unknown exception
                            uialert(app.BrainWavesUIFigure, 'Cannot Run Function', 'System Error');
                        end
                    end
                end

                
                % Batch LFP data loaded
                if isequal(app.MODE, 'BATCH')

                    % Exceptions during running
                    if isempty(app.BATCH_LFP)  % No batch exception
                        uialert(app.BrainWavesUIFigure, 'Nothing in Batch LFP', 'Data Handling Error');
                    end
                    
                    if isempty(app.BatchTree.CheckedNodes) % No checked node exception
                        uialert(app.BrainWavesUIFigure, 'No Checked Nodes', 'Data Handling Error');
                    end
                    
                    % Run function
                    if ~isempty(app.BATCH_LFP) && ~isempty(app.BatchTree.CheckedNodes)
                        try
                            % Reset variables
                            app.SELECTED_NODES = struct;

                            % Get selected node from batch tree
                            app.SELECTED_NODES = app.getCheckedLFP(app.BATCH_LFP, app.BatchTree.CheckedNodes);
                            
                            % Show LFP
                            app.DialogAppShowLFP = DialogShowLFP(app, app.SELECTED_NODES, app.MODE);
                        catch
                            % Unknown exception
                            uialert(app.BrainWavesUIFigure, 'Cannot Run Function', 'System Error');
                        end
                    end
                end
            end

            if isequal(AnalysisMode, 'Power Spectrum')


                % Single LFP data loaded
                if isequal(app.MODE, 'SINGLE')

                    % Exceptions during running
                    if isempty(app.LFP) % No data exception
                        uialert(app.BrainWavesUIFigure, 'Nothing in LFP', 'Data Handling Error');
                    end
                    
                    % Run function
                    if ~isempty(app.LFP)
                        try
                            % Power spectrum
                            app.DialogAppPowerSpectrum = DialogPowerSpectrum(app, app.LFP, app.MODE);
                        catch
                            % Unknown exception
                            uialert(app.BrainWavesUIFigure, 'Cannot Run Function', 'System Error');
                        end
                    end
                end

                % Batch LFP data loaded
                if isequal(app.MODE, 'BATCH')
                    
                    % Exceptions during running
                    if isempty(app.BATCH_LFP) % No batch exception
                        uialert(app.BrainWavesUIFigure, 'Nothing in Batch LFP', 'Data Handling Error');
                    end

                    if isempty(app.BatchTree.CheckedNodes) % No checked node exception
                        uialert(app.BrainWavesUIFigure, 'No Checked Nodes', 'Data Handling Error');
                    end

                    % Run function
                    if ~isempty(app.BATCH_LFP) && ~isempty(app.BatchTree.CheckedNodes)
                        try
                            % Reset variables
                            app.SELECTED_NODES = struct;

                            % Get selected node from batch tree
                            app.SELECTED_NODES = app.getCheckedLFP(app.BATCH_LFP, app.BatchTree.CheckedNodes);
            
                            % Run function
                            app.DialogAppPowerSpectrum = DialogPowerSpectrum(app, app.SELECTED_NODES, app.MODE);
                        catch
                            % Unknown exception
                            uialert(app.BrainWavesUIFigure, 'Cannot Run Function', 'System Error');
                        end
                    end
                end
            end

            % Enable button
            app.switchStatus('on');
        end

        % Close request function: BrainWavesUIFigure
        function BrainWavesUIFigureCloseRequest(app, event)
            delete(app.DialogAppShowLFP);
            delete(app);
        end

        % Selection change function: GraphTabGroup
        function GraphTabGroupSelectionChanged(app, event)
            app.updateCurrentPlotIndex(); % Update plot index
        end

        % Menu selected function: DeleteMenu
        function DeleteMenuSelected(app, event)
            % Find row
            index = Inf;
            for i = 1:numel(app.PLOTTAB)
                if isequal(app.PLOTTAB(i).INDEX, app.I_CURRENT_PLOT)
                    index = i; % Update matched index
                end
            end

            % For welcome tab
            if isequal(app.PLOTTAB(index).INDEX, 1)
                delete(app.PLOTTAB(index).TAB); % Delete welcome tab
                app.PLOTTAB(index).TAB = []; % Reset tab object
            end

            % For other tab
            if (app.PLOTTAB(index).INDEX > 1)
                delete(app.PLOTTAB(index).TAB); % Delete selected tab
                app.PLOTTAB(index) = []; % Remove saved data
            end

            % Update plot index
            app.updateCurrentPlotIndex();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BrainWavesUIFigure = uifigure('Visible', 'off');
            app.BrainWavesUIFigure.Name = 'BrainWaves';
            app.BrainWavesUIFigure.Icon = fullfile(pathToMLAPP, 'BrainWaves_resources', 'icon_48.png');
            app.BrainWavesUIFigure.CloseRequestFcn = createCallbackFcn(app, @BrainWavesUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.BrainWavesUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.Text = 'Import';
            app.ImportLFPMenu = uimenu(app.ImportMenu);
            app.ImportLFPMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportLFPMenuSelected, true);
            app.ImportLFPMenu.Text = 'Import LFP';
            app.ImportBatchMenu = uimenu(app.ImportMenu);
            app.ImportBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportBatchMenuSelected, true);
            app.ImportBatchMenu.Text = 'Import Batch';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Text = 'Export';
            app.ExportPlotDataMenu = uimenu(app.ExportMenu);
            app.ExportPlotDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportPlotDataMenuSelected, true);
            app.ExportPlotDataMenu.Text = 'Export Plot Data';
            app.LeftPanel = uipanel(app.BrainWavesUIFigure);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.PathEditField = uieditfield(app.GridLayout2, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            app.PathEditField.Editable = 'off';
            app.PathEditField.Placeholder = 'Selected path ...';
            app.DataLoadedLampLabel = uilabel(app.GridLayout2);
            app.DataLoadedLampLabel.Text = 'Data Loaded';
            app.DataLoadedLamp = uilamp(app.GridLayout2);
            app.SelectAnlysisDropDown = uidropdown(app.GridLayout2);
            app.SelectAnlysisDropDown.Items = {'Show LFP', 'Power Spectrum'};
            app.SelectAnlysisDropDown.Placeholder = 'Select Analysis ...';
            app.SelectAnlysisDropDown.Value = 'Show LFP';
            app.RunAnalysisButton = uibutton(app.GridLayout2, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.CenterPanel = uipanel(app.BrainWavesUIFigure);
            app.CentralGridLayout = uigridlayout(app.CenterPanel);
            app.GraphTabGroup = uitabgroup(app.CentralGridLayout);
            app.GraphTabGroup.SelectionChangedFcn = createCallbackFcn(app, @GraphTabGroupSelectionChanged, true);
            app.WelcomeTab = uitab(app.GraphTabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.WelcomeGridLayout = uigridlayout(app.WelcomeTab);
            app.Image = uiimage(app.WelcomeGridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Resources', 'logo_square_small.svg');
            app.BatchPanel = uipanel(app.BrainWavesUIFigure);
            app.BatchPanel.Title = 'Batch Panel';
            app.BatchTreeGridLayout = uigridlayout(app.BatchPanel);
            app.BatchTree = uitree(app.BatchTreeGridLayout, 'checkbox');
            app.AnalysisMonitorPanel = uipanel(app.BrainWavesUIFigure);
            app.AnalysisMonitorPanel.Title = 'Analysis Monitor';
            app.MonitorGridLayout = uigridlayout(app.AnalysisMonitorPanel);
            app.AnalysisMonitorGridLayout = uigridlayout(app.MonitorGridLayout);
            app.ChorNLabel = uilabel(app.AnalysisMonitorGridLayout);
            app.ChorNLabel.Text = 'Ch.(or N)';
            app.ChorNEditField = uieditfield(app.AnalysisMonitorGridLayout, 'numeric');
            app.ChorNEditField.Editable = 'off';
            app.AnalyzingFsEditFieldLabel = uilabel(app.AnalysisMonitorGridLayout);
            app.AnalyzingFsEditFieldLabel.Text = 'Analyzing Fs';
            app.AnalyzingFsEditField = uieditfield(app.AnalysisMonitorGridLayout, 'numeric');
            app.AnalyzingFsEditField.Editable = 'off';
            app.SNRdBEditFieldLabel = uilabel(app.AnalysisMonitorGridLayout);
            app.SNRdBEditFieldLabel.Text = 'SNR (dB)';
            app.SNRdBEditField = uieditfield(app.AnalysisMonitorGridLayout, 'numeric');
            app.SNRdBEditField.Editable = 'off';
            app.GraphTabContextMenu = uicontextmenu(app.BrainWavesUIFigure);
            app.DeleteMenu = uimenu(app.GraphTabContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.GraphTabContextMenu
            app.WelcomeTab.ContextMenu = app.GraphTabContextMenu;
            % Show the figure after all components are created
            app.BrainWavesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BrainWaves

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BrainWavesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BrainWavesUIFigure)
        end
    end
end