classdef DialogBatchSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BatchSelectorUIFigure  matlab.ui.Figure
        CancelButton           matlab.ui.control.Button
        OKButton               matlab.ui.control.Button
        Panel                  matlab.ui.container.Panel
        GridLayout             matlab.ui.container.GridLayout
        BatchNameEditField_14  matlab.ui.control.EditField
        BatchNameEditField_13  matlab.ui.control.EditField
        BatchNameEditField_12  matlab.ui.control.EditField
        BatchNameEditField_11  matlab.ui.control.EditField
        BatchNameEditField_10  matlab.ui.control.EditField
        BatchNameEditField_9   matlab.ui.control.EditField
        BatchNameEditField_8   matlab.ui.control.EditField
        BatchNameEditField_7   matlab.ui.control.EditField
        BatchNameEditField_6   matlab.ui.control.EditField
        BatchNameEditField_5   matlab.ui.control.EditField
        BatchNameEditField_4   matlab.ui.control.EditField
        BatchNameEditField_3   matlab.ui.control.EditField
        BatchNameEditField_2   matlab.ui.control.EditField
        BatchNameEditField_1   matlab.ui.control.EditField
        Button_14              matlab.ui.control.Button
        Button_13              matlab.ui.control.Button
        Button_12              matlab.ui.control.Button
        Button_11              matlab.ui.control.Button
        Button_10              matlab.ui.control.Button
        Button_9               matlab.ui.control.Button
        Button_8               matlab.ui.control.Button
        Button_7               matlab.ui.control.Button
        Button_6               matlab.ui.control.Button
        Button_5               matlab.ui.control.Button
        Button_4               matlab.ui.control.Button
        Button_3               matlab.ui.control.Button
        Button_2               matlab.ui.control.Button
        Button_1               matlab.ui.control.Button
        EditField_14           matlab.ui.control.EditField
        EditField_13           matlab.ui.control.EditField
        EditField_12           matlab.ui.control.EditField
        EditField_11           matlab.ui.control.EditField
        EditField_10           matlab.ui.control.EditField
        EditField_9            matlab.ui.control.EditField
        EditField_8            matlab.ui.control.EditField
        EditField_7            matlab.ui.control.EditField
        EditField_6            matlab.ui.control.EditField
        EditField_5            matlab.ui.control.EditField
        EditField_4            matlab.ui.control.EditField
        EditField_3            matlab.ui.control.EditField
        EditField_2            matlab.ui.control.EditField
        EditField_1            matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Property % Description

        MainBrainWaves % CallingApp
        BATCH_PATH_LIST % Path list of batch
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            % Store main app in property for CloseRequestFcn to use
            app.MainBrainWaves = caller;

            % Temporary disable main window switches
            switchStatus(app.MainBrainWaves, 'off');
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_1.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_1.Value = path; % Change path field value
                app.BATCH_PATH_LIST(1).index = 1; % Batch index
                app.BATCH_PATH_LIST(1).batch_name = app.BatchNameEditField_1.Value; % Batch name
                app.BATCH_PATH_LIST(1).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_1
        function BatchNameEditField_1ValueChanged(app, event)
            value = app.BatchNameEditField_1.Value;
            
            app.BATCH_PATH_LIST(1).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_2.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_2.Value = path; % Change path field value
                app.BATCH_PATH_LIST(2).index = 2; % Batch index
                app.BATCH_PATH_LIST(2).batch_name = app.BatchNameEditField_2.Value; % Batch name
                app.BATCH_PATH_LIST(2).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_2
        function BatchNameEditField_2ValueChanged(app, event)
            value = app.BatchNameEditField_2.Value;
            
            app.BATCH_PATH_LIST(2).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_3.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_3.Value = path; % Change path field value
                app.BATCH_PATH_LIST(3).index = 3; % Batch index
                app.BATCH_PATH_LIST(3).batch_name = app.BatchNameEditField_3.Value; % Batch name
                app.BATCH_PATH_LIST(3).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_3
        function BatchNameEditField_3ValueChanged(app, event)
            value = app.BatchNameEditField_3.Value;

            app.BATCH_PATH_LIST(3).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_4.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_4.Value = path; % Change path field value
                app.BATCH_PATH_LIST(4).index = 4; % Batch index
                app.BATCH_PATH_LIST(4).batch_name = app.BatchNameEditField_4.Value; % Batch name
                app.BATCH_PATH_LIST(4).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_4
        function BatchNameEditField_4ValueChanged(app, event)
            value = app.BatchNameEditField_4.Value;
            
            app.BATCH_PATH_LIST(4).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_5.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_5.Value = path; % Change path field value
                app.BATCH_PATH_LIST(5).index = 5; % Batch index
                app.BATCH_PATH_LIST(5).batch_name = app.BatchNameEditField_5.Value; % Batch name
                app.BATCH_PATH_LIST(5).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_5
        function BatchNameEditField_5ValueChanged(app, event)
            value = app.BatchNameEditField_5.Value;
            
            app.BATCH_PATH_LIST(5).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_6.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_6.Value = path; % Change path field value
                app.BATCH_PATH_LIST(6).index = 6; % Batch index
                app.BATCH_PATH_LIST(6).batch_name = app.BatchNameEditField_6.Value; % Batch name
                app.BATCH_PATH_LIST(6).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_6
        function BatchNameEditField_6ValueChanged(app, event)
            value = app.BatchNameEditField_6.Value;
            
            app.BATCH_PATH_LIST(6).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_7.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_7.Value = path; % Change path field value
                app.BATCH_PATH_LIST(7).index = 7; % Batch index
                app.BATCH_PATH_LIST(7).batch_name = app.BatchNameEditField_7.Value; % Batch name
                app.BATCH_PATH_LIST(7).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_7
        function BatchNameEditField_7ValueChanged(app, event)
            value = app.BatchNameEditField_7.Value;
            
            app.BATCH_PATH_LIST(7).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_8.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_8.Value = path; % Change path field value
                app.BATCH_PATH_LIST(8).index = 8; % Batch index
                app.BATCH_PATH_LIST(8).batch_name = app.BatchNameEditField_8.Value; % Batch name
                app.BATCH_PATH_LIST(8).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_8
        function BatchNameEditField_8ValueChanged(app, event)
            value = app.BatchNameEditField_8.Value;
            
            app.BATCH_PATH_LIST(8).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_9.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_9.Value = path; % Change path field value
                app.BATCH_PATH_LIST(9).index = 9; % Batch index
                app.BATCH_PATH_LIST(9).batch_name = app.BatchNameEditField_9.Value; % Batch name
                app.BATCH_PATH_LIST(9).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_9
        function BatchNameEditField_9ValueChanged(app, event)
            value = app.BatchNameEditField_9.Value;
            
            app.BATCH_PATH_LIST(9).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_10.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_10.Value = path; % Change path field value
                app.BATCH_PATH_LIST(10).index = 10; % Batch index
                app.BATCH_PATH_LIST(10).batch_name = app.BatchNameEditField_10.Value; % Batch name
                app.BATCH_PATH_LIST(10).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_10
        function BatchNameEditField_10ValueChanged(app, event)
            value = app.BatchNameEditField_10.Value;
            
            app.BATCH_PATH_LIST(10).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_11.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_11.Value = path; % Change path field value
                app.BATCH_PATH_LIST(11).index = 11; % Batch index
                app.BATCH_PATH_LIST(11).batch_name = app.BatchNameEditField_11.Value; % Batch name
                app.BATCH_PATH_LIST(11).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_11
        function BatchNameEditField_11ValueChanged(app, event)
            value = app.BatchNameEditField_11.Value;
            
            app.BATCH_PATH_LIST(11).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_12.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_12.Value = path; % Change path field value
                app.BATCH_PATH_LIST(12).index = 12; % Batch index
                app.BATCH_PATH_LIST(12).batch_name = app.BatchNameEditField_12.Value; % Batch name
                app.BATCH_PATH_LIST(12).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_12
        function BatchNameEditField_12ValueChanged(app, event)
            value = app.BatchNameEditField_12.Value;
            
            app.BATCH_PATH_LIST(12).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_13.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_13.Value = path; % Change path field value
                app.BATCH_PATH_LIST(13).index = 13; % Batch index
                app.BATCH_PATH_LIST(13).batch_name = app.BatchNameEditField_13.Value; % Batch name
                app.BATCH_PATH_LIST(13).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_13
        function BatchNameEditField_13ValueChanged(app, event)
            value = app.BatchNameEditField_13.Value;
            
            app.BATCH_PATH_LIST(13).batch_name = value; % Update batch name
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            path = uigetdir(pwd, 'Select data folder');
            
            if ~isfolder(path)
                app.EditField_14.Value = event.PreviousValue;
                uialert(app.BatchSelectorUIFigure, 'Directory not found', 'Path Error');
            end

            if isfolder(path)
                % Update fields
                app.EditField_14.Value = path; % Change path field value
                app.BATCH_PATH_LIST(14).index = 14; % Batch index
                app.BATCH_PATH_LIST(14).batch_name = app.BatchNameEditField_14.Value; % Batch name
                app.BATCH_PATH_LIST(14).path = path; % Allocate batch path
            end
        end

        % Value changed function: BatchNameEditField_14
        function BatchNameEditField_14ValueChanged(app, event)
            value = app.BatchNameEditField_14.Value;
            
            app.BATCH_PATH_LIST(14).batch_name = value; % Update batch name
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % Check duplicated batch names;
            batch_names_for_check = [];
            isduplicated = 'no'; % Change condition
            for i = 1:numel(app.BATCH_PATH_LIST)
                batch_names_for_check = [batch_names_for_check, string(num2str(app.BATCH_PATH_LIST(i).batch_name))];
            end

            UNIQUE = unique(batch_names_for_check); % Exclude duplicated elements
            if isequal(sort(batch_names_for_check), sort(UNIQUE)) % No duplication
                % Change condition
                isduplicated = 'no';
            end

            if ~isequal(sort(batch_names_for_check), sort(UNIQUE)) % With duplication
                % Alert error on main window
                uialert(app.BatchSelectorUIFigure, 'Batch Name Duplicated', 'Value Error');

                % Change condition
                isduplicated = 'yes';
            end

            % No batch assigned exception
            if isempty(app.BATCH_PATH_LIST)
                % Alert error on main window
                uialert(app.BatchSelectorUIFigure, 'No Batch Selected', 'Path Error');
            end

            % No path assigned exception
            if ~isfield(app.BATCH_PATH_LIST, 'path')
                % Alert error on main window
                uialert(app.BatchSelectorUIFigure, 'No Path Selected', 'Path Error');
            end
            
            % Run import
            if ~isempty(app.BATCH_PATH_LIST) && isequal(isduplicated, 'no') && isfield(app.BATCH_PATH_LIST, 'path')
                % Import batch
                callBatchLFP(app.MainBrainWaves, app.BATCH_PATH_LIST);

                % Delete the dialog box
                delete(app);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Plot Run Analysis button in main app, if the app is
            % still open
            if isvalid(app.MainBrainWaves)
                switchStatus(app.MainBrainWaves, 'on');
                startupSwitchStatus(app.MainBrainWaves, 'off');
            end
            
            % Delete the dialog box 
            delete(app);
        end

        % Close request function: BatchSelectorUIFigure
        function BatchSelectorUIFigureCloseRequest(app, event)
            % Enable the Plot Run Analysis button in main app, if the app is
            % still open
            if isvalid(app.MainBrainWaves)
                switchStatus(app.MainBrainWaves, 'on');
                startupSwitchStatus(app.MainBrainWaves, 'off');
            end
            
            % Delete the dialog box 
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.BatchSelectorUIFigure = uifigure('Visible', 'off');
            app.BatchSelectorUIFigure.Name = 'Batch Selector';
            app.BatchSelectorUIFigure.Icon = fullfile(pathToMLAPP, 'BrainWaves_resources', 'icon_48.png');
            app.BatchSelectorUIFigure.CloseRequestFcn = createCallbackFcn(app, @BatchSelectorUIFigureCloseRequest, true);
            app.Panel = uipanel(app.BatchSelectorUIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.EditField_1 = uieditfield(app.GridLayout, 'text');
            app.EditField_1.Editable = 'off';
            app.EditField_1.Placeholder = 'Selected path ...';
            app.EditField_2 = uieditfield(app.GridLayout, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Placeholder = 'Selected path ...';
            app.EditField_3 = uieditfield(app.GridLayout, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Placeholder = 'Selected path ...';
            app.EditField_4 = uieditfield(app.GridLayout, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Placeholder = 'Selected path ...';
            app.EditField_5 = uieditfield(app.GridLayout, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Placeholder = 'Selected path ...';
            app.EditField_6 = uieditfield(app.GridLayout, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Placeholder = 'Selected path ...';
            app.EditField_7 = uieditfield(app.GridLayout, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Placeholder = 'Selected path ...';
            app.EditField_8 = uieditfield(app.GridLayout, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_8.Placeholder = 'Selected path ...';
            app.EditField_9 = uieditfield(app.GridLayout, 'text');
            app.EditField_9.Editable = 'off';
            app.EditField_9.Placeholder = 'Selected path ...';
            app.EditField_10 = uieditfield(app.GridLayout, 'text');
            app.EditField_10.Editable = 'off';
            app.EditField_10.Placeholder = 'Selected path ...';
            app.EditField_11 = uieditfield(app.GridLayout, 'text');
            app.EditField_11.Editable = 'off';
            app.EditField_11.Placeholder = 'Selected path ...';
            app.EditField_12 = uieditfield(app.GridLayout, 'text');
            app.EditField_12.Editable = 'off';
            app.EditField_12.Placeholder = 'Selected path ...';
            app.EditField_13 = uieditfield(app.GridLayout, 'text');
            app.EditField_13.Editable = 'off';
            app.EditField_13.Placeholder = 'Selected path ...';
            app.EditField_14 = uieditfield(app.GridLayout, 'text');
            app.EditField_14.Editable = 'off';
            app.EditField_14.Placeholder = 'Selected path ...';
            app.Button_1 = uibutton(app.GridLayout, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '▼';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '▼';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '▼';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '▼';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '▼';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '▼';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '▼';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '▼';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '▼';
            app.Button_10 = uibutton(app.GridLayout, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '▼';
            app.Button_11 = uibutton(app.GridLayout, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '▼';
            app.Button_12 = uibutton(app.GridLayout, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '▼';
            app.Button_13 = uibutton(app.GridLayout, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '▼';
            app.Button_14 = uibutton(app.GridLayout, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '▼';
            app.BatchNameEditField_1 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_1.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_1ValueChanged, true);
            app.BatchNameEditField_1.Placeholder = 'Batch 1 name ...';
            app.BatchNameEditField_2 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_2.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_2ValueChanged, true);
            app.BatchNameEditField_2.Placeholder = 'Batch 2 name ...';
            app.BatchNameEditField_3 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_3.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_3ValueChanged, true);
            app.BatchNameEditField_3.Placeholder = 'Batch 3 name ...';
            app.BatchNameEditField_4 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_4.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_4ValueChanged, true);
            app.BatchNameEditField_4.Placeholder = 'Batch 4 name ...';
            app.BatchNameEditField_5 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_5.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_5ValueChanged, true);
            app.BatchNameEditField_5.Placeholder = 'Batch 5 name ...';
            app.BatchNameEditField_6 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_6.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_6ValueChanged, true);
            app.BatchNameEditField_6.Placeholder = 'Batch 6 name ...';
            app.BatchNameEditField_7 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_7.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_7ValueChanged, true);
            app.BatchNameEditField_7.Placeholder = 'Batch 7 name ...';
            app.BatchNameEditField_8 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_8.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_8ValueChanged, true);
            app.BatchNameEditField_8.Placeholder = 'Batch 8 name ...';
            app.BatchNameEditField_9 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_9.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_9ValueChanged, true);
            app.BatchNameEditField_9.Placeholder = 'Batch 9 name ...';
            app.BatchNameEditField_10 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_10.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_10ValueChanged, true);
            app.BatchNameEditField_10.Placeholder = 'Batch 10 name ...';
            app.BatchNameEditField_11 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_11.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_11ValueChanged, true);
            app.BatchNameEditField_11.Placeholder = 'Batch 11 name ...';
            app.BatchNameEditField_12 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_12.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_12ValueChanged, true);
            app.BatchNameEditField_12.Placeholder = 'Batch 12 name ...';
            app.BatchNameEditField_13 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_13.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_13ValueChanged, true);
            app.BatchNameEditField_13.Placeholder = 'Batch 13 name ...';
            app.BatchNameEditField_14 = uieditfield(app.GridLayout, 'text');
            app.BatchNameEditField_14.ValueChangedFcn = createCallbackFcn(app, @BatchNameEditField_14ValueChanged, true);
            app.BatchNameEditField_14.Placeholder = 'Batch 14 name ...';
            app.OKButton = uibutton(app.BatchSelectorUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.BatchSelectorUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.BatchSelectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogBatchSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BatchSelectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BatchSelectorUIFigure)
        end
    end
end