classdef DialogPowerSpectrum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PowerSpectrumUIFigure         matlab.ui.Figure
        NormalizeModeDropDown         matlab.ui.control.DropDown
        NormalizeModeDropDownLabel    matlab.ui.control.Label
        AnalyzingFrequencyEditField   matlab.ui.control.NumericEditField
        AnalyzingFrequencyEditFieldLabel  matlab.ui.control.Label
        ChannelDropDown               matlab.ui.control.DropDown
        ChannelDropDownLabel          matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
        TransformMethodDropDown       matlab.ui.control.DropDown
        TransformMethodDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        
        MainBrainWaves % CallingApp
        data % from Main
        MODE % Batch type: SINGLE or BATCH
        BATCH_CONTENT_STRING % Selected batch contents
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, data, mode)
             % Store main app in property for CloseRequestFcn to use
            app.MainBrainWaves = caller;

            % Reset mode
            app.MODE = mode;

            % Update UI with input values
            if isequal(app.MODE, 'SINGLE')

                % No data exception
                if isempty(data)
                    % Alert no data error
                    uialert(app.MainBrainWaves.BrainWavesUIFigure, 'No Passed Data', 'Data Handling Error');

                    % Enable the Plot Run Analysis button in main app, if the app is
                    % still open
                    if isvalid(app.MainBrainWaves)
                        switchStatus(app.MainBrainWaves, 'on');
                    end

                    % Delete dialog box
                    delete(app);
                end

                % Start dialog
                if ~isempty(data)
                    try
                        % Assign data
                        app.data = data;
                        app.ChannelDropDown.Items = arrayfun(@num2str, [app.data.channel], 'UniformOutput', 0);
                    catch
                        uialert(app.MainBrainWaves.BrainWavesUIFigure, 'Cannot Start Dialog: Power Spectrum', 'System Error'); % alert error
        
                        % Enable the Plot Run Analysis button in main app, if the app is
                        % still open
                        if isvalid(app.MainBrainWaves)
                            switchStatus(app.MainBrainWaves, 'on');
                        end
        
                        % Delete the dialog box
                        delete(app);
                    end
                end
            end

            if isequal(app.MODE, 'BATCH')

                % No data exception
                if isempty(data)
                    % Alert no data error
                    uialert(app.MainBrainWaves.BrainWavesUIFigure, 'No Passed Data', 'Data Handling Error');

                    % Enable the Plot Run Analysis button in main app, if the app is
                    % still open
                    if isvalid(app.MainBrainWaves)
                        switchStatus(app.MainBrainWaves, 'on');
                    end

                    % Delete dialog box
                    delete(app);
                end

                % Start dialog
                if ~isempty(data)
                    try
                        % Assign data
                        app.data = data;
                        k = app.data;
                        % Reset batch items
                        batch_items = [];

                        % Reset batch contents string
                        app.BATCH_CONTENT_STRING = [];
                        
                        % Get batch items: 'batch_name: Channel', mininum_end_time
                        for batch = 1:numel(app.data)
                            % Get batch name
                            batch_name = app.data(batch).batch_name;
                    
	                         % Reset batch channels
	                        batch_channels = [];
                        
	                        for i = 1:numel(app.data(batch).LFP)
		                        batch_channels = [batch_channels, app.data(batch).LFP(i).channel];
                            end

                             % Make batch channel items
                            for ch = 1:numel(batch_channels)
	                            batch_items = [batch_items, {strcat(batch_name, '/', num2str(batch_channels(ch)))}];
                            end
                        end

                        % Merge batch content string
                        for i = 1:numel(batch_items)
                            app.BATCH_CONTENT_STRING = [app.BATCH_CONTENT_STRING, '_', cell2mat(batch_items(i))];
                        end
                        app.BATCH_CONTENT_STRING = strcat('BATCH', app.BATCH_CONTENT_STRING);

                        % Update channel dropdown
                        app.ChannelDropDown.Items = {app.BATCH_CONTENT_STRING}; % For batch power spectrum, show mean ± SEM power from selected channels
                    catch
                        uialert(app.MainBrainWaves.BrainWavesUIFigure, 'Cannot Start Dialog: Power Spectrum', 'System Error'); % alert error
        
                        % Enable the Plot Run Analysis button in main app, if the app is
                        % still open
                        if isvalid(app.MainBrainWaves)
                            switchStatus(app.MainBrainWaves, 'on');
                        end
        
                        % Delete the dialog box
                        delete(app);
                    end
                end
            end            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % For single LFP
            if isequal(app.MODE, 'SINGLE')

                % Analyzing frequency exception
                if app.AnalyzingFrequencyEditField.Value > app.data(1).fs
                    AnalyzingFs = app.data(1).fs;
                end
    
                if app.AnalyzingFrequencyEditField.Value <= app.data(1).fs
                    AnalyzingFs = app.AnalyzingFrequencyEditField.Value;
                end
                
                % Call main app's public function
                if isequal(app.TransformMethodDropDown.Value, 'Wavelet')
                    % Run Wavelet Transform
                    waveletPowerSpectrum(app.MainBrainWaves, app.data, str2num(app.ChannelDropDown.Value), AnalyzingFs, app.NormalizeModeDropDown.Value, app.MODE, app.ChannelDropDown.Value);
                end
            end

            % For batch LFP
            if isequal(app.MODE, 'BATCH')
                % Reset variables
                minimum_batch_index = Inf;
                minimun_channel_index = Inf;
                
                % Get minimum index where data is located in it
                for batch = 1:numel(app.data)
                    if ~isempty(app.data(batch).LFP)
                        for i = 1:numel(app.data(batch).LFP)
                            if ~isempty(app.data(batch).LFP(i))
                                if batch < minimum_batch_index
                                    minimum_batch_index = batch;
                                end
                                if i < minimun_channel_index
                                    minimun_channel_index = i;
                                end
                            end
                        end
                    end
                end

                % Analyzing frequency exception
                if app.AnalyzingFrequencyEditField.Value > app.data(minimum_batch_index).LFP(minimun_channel_index).fs
                    AnalyzingFs = app.data(minimum_batch_index).LFP(minimun_channel_index).fs;
                end
    
                if app.AnalyzingFrequencyEditField.Value <= app.data(minimum_batch_index).LFP(minimun_channel_index).fs
                    AnalyzingFs = app.AnalyzingFrequencyEditField.Value;
                end
                
                % Call main app's public function
                if isequal(app.TransformMethodDropDown.Value, 'Wavelet')
                    % Run Wavelet Transform
                    waveletPowerSpectrum(app.MainBrainWaves, app.data, str2num(app.ChannelDropDown.Value), AnalyzingFs, app.NormalizeModeDropDown.Value, app.MODE, app.ChannelDropDown.Value);
                end
            end
            
            % Delete the dialog box
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Plot Run Analysis button in main app, if the app is
            % still open
            if isvalid(app.MainBrainWaves)
                switchStatus(app.MainBrainWaves, 'on');
            end

            % Delete the dialog box
            delete(app);
        end

        % Close request function: PowerSpectrumUIFigure
        function PowerSpectrumUIFigureCloseRequest(app, event)
            % Enable the Plot Run Analysis button in main app, if the app is
            % still open
            if isvalid(app.MainBrainWaves)
                switchStatus(app.MainBrainWaves, 'on');
            end
            
            % Delete the dialog box 
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.PowerSpectrumUIFigure = uifigure('Visible', 'off');
            app.PowerSpectrumUIFigure.Name = 'Power Spectrum';
            app.PowerSpectrumUIFigure.Icon = fullfile(pathToMLAPP, 'BrainWaves_resources', 'icon_48.png');
            app.PowerSpectrumUIFigure.CloseRequestFcn = createCallbackFcn(app, @PowerSpectrumUIFigureCloseRequest, true);
            app.TransformMethodDropDownLabel = uilabel(app.PowerSpectrumUIFigure);
            app.TransformMethodDropDownLabel.Text = 'Transform Method';
            app.TransformMethodDropDown = uidropdown(app.PowerSpectrumUIFigure);
            app.TransformMethodDropDown.Items = {'Wavelet'};
            app.TransformMethodDropDown.Value = 'Wavelet';
            app.OKButton = uibutton(app.PowerSpectrumUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.PowerSpectrumUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ChannelDropDownLabel = uilabel(app.PowerSpectrumUIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.PowerSpectrumUIFigure);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.Placeholder = 'Select Channels';
            app.ChannelDropDown.Value = {};
            app.AnalyzingFrequencyEditFieldLabel = uilabel(app.PowerSpectrumUIFigure);
            app.AnalyzingFrequencyEditFieldLabel.Text = 'Analyzing Frequency';
            app.AnalyzingFrequencyEditField = uieditfield(app.PowerSpectrumUIFigure, 'numeric');
            app.AnalyzingFrequencyEditField.Placeholder = 'Input Frequency';
            app.AnalyzingFrequencyEditField.Value = 2000;
            app.NormalizeModeDropDownLabel = uilabel(app.PowerSpectrumUIFigure);
            app.NormalizeModeDropDownLabel.Text = 'Normalize Mode';
            app.NormalizeModeDropDown = uidropdown(app.PowerSpectrumUIFigure);
            app.NormalizeModeDropDown.Items = {'No norm.', 'RMS', '% RMS', 'Z-Score'};
            app.NormalizeModeDropDown.Value = 'RMS';
            % Show the figure after all components are created
            app.PowerSpectrumUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogPowerSpectrum(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PowerSpectrumUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PowerSpectrumUIFigure)
        end
    end
end