classdef DialogShowLFP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShowLFPUIFigure              matlab.ui.Figure
        NormalizeSwitch              matlab.ui.control.Switch
        DownsampleSwitch             matlab.ui.control.Switch
        NormalizeModeDropDown        matlab.ui.control.DropDown
        NormalizeModeDropDownLabel   matlab.ui.control.Label
        AnalyzingFrequencyEditField  matlab.ui.control.NumericEditField
        AnalyzingFrequencyEditFieldLabel  matlab.ui.control.Label
        CancelButton                 matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        StartTimeEditField           matlab.ui.control.NumericEditField
        EndTimeEditField             matlab.ui.control.NumericEditField
        StartEndsEditFieldLabel      matlab.ui.control.Label
        OKButton                     matlab.ui.control.Button
        ChannelDropDown              matlab.ui.control.DropDown
        ChannelDropDownLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description

        MainBrainWaves % CallingApp
        data % from Main
        MODE % Batch type: SINGLE or BATCH
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, data, mode)
            % Store main app in property for CloseRequestFcn to use
            app.MainBrainWaves = caller;

            % Reset mode
            app.MODE = mode;

            % Update UI with input values
            if isequal(app.MODE, 'SINGLE')

                % No data exception
                if isempty(data)
                    % Alert no data error
                    uialert(app.MainBrainWaves.BrainWavesUIFigure, 'No Passed Data', 'Data Handling Error');

                    % Enable the Plot Run Analysis button in main app, if the app is
                    % still open
                    if isvalid(app.MainBrainWaves)
                        switchStatus(app.MainBrainWaves, 'on');
                    end

                    % Delete dialog box
                    delete(app);
                end

                % Start dialog
                if ~isempty(data)
                    try
                        % Assign data
                        app.data = data; 
                        app.ChannelDropDown.Items = arrayfun(@num2str, [app.data.channel], 'UniformOutput', 0); % Update channel items
                        app.StartTimeEditField.Value = floor(app.data(1).ts(1)); % Update start time
                        app.EndTimeEditField.Value =  floor(app.data(1).ts(end)); % Update end time

                    catch
                        uialert(app.MainBrainWaves.BrainWavesUIFigure, 'Cannot Start Dialog: Show LFP', 'System Error'); % alert error
        
                        % Enable the Plot Run Analysis button in main app, if the app is
                        % still open
                        if isvalid(app.MainBrainWaves)
                            switchStatus(app.MainBrainWaves, 'on');
                        end
        
                        % Delete the dialog box
                        delete(app);
                    end
                end
            end
            
            % Update UI with input values
            if isequal(app.MODE, 'BATCH')

                % No data exception
                if isempty(data)
                    % Alert no data error
                    uialert(app.MainBrainWaves.BrainWavesUIFigure, 'No Passed Data', 'Data Handling Error');

                    % Enable the Plot Run Analysis button in main app, if the app is
                    % still open
                    if isvalid(app.MainBrainWaves)
                        switchStatus(app.MainBrainWaves, 'on');
                    end

                    % Delete dialog box
                    delete(app);
                end

                % Start dialog
                if ~isempty(data)
                    try
                        app.data = data;  % Assign data
                        batch_items = []; % Reset batch items
                        min_end_time = Inf; % Reset minimum end time

                        % Reset variables
                        minimum_batch_index = Inf;
                        minimun_channel_index = Inf;
                        
                        % Get minimum index where data is located in it
                        for batch = 1:numel(app.data)
                            if ~isempty(app.data(batch).LFP)
                                for i = 1:numel(app.data(batch).LFP)
                                    if ~isempty(app.data(batch).LFP(i))
                                        if batch < minimum_batch_index
                                            minimum_batch_index = batch;
                                        end
                                        if i < minimun_channel_index
                                            minimun_channel_index = i;
                                        end
                                    end
                                end
                            end
                        end
                        
                        % Get batch items: 'batch_name: Channel', mininum_end_time
                        for batch = 1:numel(app.data)
    
                            % Get batch name
                            batch_name = app.data(batch).batch_name;
                            
                            % Reset batch channels
                            batch_channels = []; 
    
                            for i = 1:numel(app.data(batch).LFP)
                                % Get all batch channels
                                % batch_channels = arrayfun(@num2str, [app.data(batch).LFP.channel], 'UniformOutput', 0);
    
                                batch_channels = [batch_channels, app.data(batch).LFP(i).channel];
    
                                % Check end time is minimum
                                if ~(min_end_time < app.data(batch).LFP(i).ts(end))
                                    min_end_time = app.data(batch).LFP(i).ts(end);
                                end
                            end
    
                            % Make batch channel items
                            for ch = 1:numel(batch_channels)
                                batch_items = [batch_items, {strcat(batch_name, '/', num2str(batch_channels(ch)))}];
                            end
                        end
                        
                        app.ChannelDropDown.Items = batch_items; % Update channel items
                        asdf = app.data;
                        app.StartTimeEditField.Value = floor(app.data(minimum_batch_index).LFP(minimun_channel_index).ts(1)); % Update start time
                        app.EndTimeEditField.Value =  floor(min_end_time); % Update end time: Minimum end time of total batch
                    catch
                        uialert(app.MainBrainWaves.BrainWavesUIFigure, 'Cannot Start Dialog: Show LFP', 'System Error'); % alert error
        
                        % Enable the Plot Run Analysis button in main app, if the app is
                        % still open
                        if isvalid(app.MainBrainWaves)
                            switchStatus(app.MainBrainWaves, 'on');
                        end
        
                        % Delete the dialog box
                        delete(app);
                    end
                end
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            % For single LFP
            if isequal(app.MODE, 'SINGLE')

                % Start time exception
                if app.StartTimeEditField.Value < app.data(1).ts(1)
                    StartTime = app.data(1).ts(1);
                end
    
                if app.StartTimeEditField.Value >= app.data(1).ts(1)
                    StartTime = app.StartTimeEditField.Value;
                end
                
                % End time exception
                if app.EndTimeEditField.Value > app.data(1).ts(end)
                    EndTime = app.data(1).ts(end);
                end
    
                if app.EndTimeEditField.Value <= app.data(1).ts(end)
                    EndTime = app.EndTimeEditField.Value;
                end
    
                % Analyzing frequency exception
                if app.AnalyzingFrequencyEditField.Value > app.data(1).fs
                    AnalyzingFs = app.data(1).fs;
                end
    
                if app.AnalyzingFrequencyEditField.Value <= app.data(1).fs
                    AnalyzingFs = app.AnalyzingFrequencyEditField.Value;
                end
                
                % Call main app's public function
                showLFP(app.MainBrainWaves, app.data, str2num(app.ChannelDropDown.Value), [StartTime, EndTime], app.DownsampleSwitch.Value, AnalyzingFs, ...
                    app.NormalizeSwitch.Value, app.NormalizeModeDropDown.Value, app.ChannelDropDown.Value);
            end
            
            % For batch LFP
            if isequal(app.MODE, 'BATCH')
                % Get batch name and channel
                batch_and_channel = split(app.ChannelDropDown.Value, '/');
                batch_name = cell2mat(batch_and_channel(1));
                channel = cell2mat(batch_and_channel(end));

                % Find batch indices
                for batch = 1:numel(app.data)
                    if isequal(app.data(batch).batch_name, batch_name)
                        batch_idx = batch;
                    end
                end

                % Set LFP data to plot
                lfp = app.data(batch_idx).LFP;

                % Start time exception
                if app.StartTimeEditField.Value < lfp(1).ts(1)
                    StartTime = lfp(1).ts(1);
                end
    
                if app.StartTimeEditField.Value >= lfp(1).ts(1)
                    StartTime = app.StartTimeEditField.Value;
                end
                
                % End time exception
                if app.EndTimeEditField.Value > lfp(1).ts(end)
                    EndTime = lfp(1).ts(end);
                end
    
                if app.EndTimeEditField.Value <= lfp(1).ts(end)
                    EndTime = app.EndTimeEditField.Value;
                end
    
                % Analyzing frequency exception
                if app.AnalyzingFrequencyEditField.Value > lfp(1).fs
                    AnalyzingFs = lfp(1).fs;
                end
    
                if app.AnalyzingFrequencyEditField.Value <= lfp(1).fs
                    AnalyzingFs = app.AnalyzingFrequencyEditField.Value;
                end
                
                % Call main app's public function
                showLFP(app.MainBrainWaves, lfp, str2num(channel), [StartTime, EndTime], app.DownsampleSwitch.Value, AnalyzingFs, ...
                    app.NormalizeSwitch.Value, app.NormalizeModeDropDown.Value, app.ChannelDropDown.Value);
            end
            
            % Delete the dialog box
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Plot Run Analysis button in main app, if the app is
            % still open
            if isvalid(app.MainBrainWaves)
                switchStatus(app.MainBrainWaves, 'on');
            end

            % Delete the dialog box
            delete(app);
        end

        % Close request function: ShowLFPUIFigure
        function ShowLFPUIFigureCloseRequest(app, event)
            % Enable the Plot Run Analysis button in main app, if the app is
            % still open
            if isvalid(app.MainBrainWaves)
                switchStatus(app.MainBrainWaves, 'on');
            end
            
            % Delete the dialog box 
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ShowLFPUIFigure = uifigure('Visible', 'off');
            app.ShowLFPUIFigure.Name = 'Show LFP';
            app.ShowLFPUIFigure.Icon = fullfile(pathToMLAPP, 'BrainWaves_resources', 'icon_48.png');
            app.ShowLFPUIFigure.CloseRequestFcn = createCallbackFcn(app, @ShowLFPUIFigureCloseRequest, true);
            app.ChannelDropDownLabel = uilabel(app.ShowLFPUIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.ShowLFPUIFigure);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.Placeholder = 'Channels';
            app.ChannelDropDown.Value = {};
            app.OKButton = uibutton(app.ShowLFPUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.StartEndsEditFieldLabel = uilabel(app.ShowLFPUIFigure);
            app.StartEndsEditFieldLabel.Text = 'Start-End (s)';
            app.EndTimeEditField = uieditfield(app.ShowLFPUIFigure, 'numeric');
            app.StartTimeEditField = uieditfield(app.ShowLFPUIFigure, 'numeric');
            app.Label = uilabel(app.ShowLFPUIFigure);
            app.Label.Text = '-';
            app.CancelButton = uibutton(app.ShowLFPUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.AnalyzingFrequencyEditFieldLabel = uilabel(app.ShowLFPUIFigure);
            app.AnalyzingFrequencyEditFieldLabel.Text = 'Analyzing Frequency';
            app.AnalyzingFrequencyEditField = uieditfield(app.ShowLFPUIFigure, 'numeric');
            app.AnalyzingFrequencyEditField.Value = 2000;
            app.NormalizeModeDropDownLabel = uilabel(app.ShowLFPUIFigure);
            app.NormalizeModeDropDownLabel.Text = 'Normalize Mode';
            app.NormalizeModeDropDown = uidropdown(app.ShowLFPUIFigure);
            app.NormalizeModeDropDown.Items = {'No norm.', 'RMS', '% RMS', 'Z-Score'};
            app.NormalizeModeDropDown.Value = 'RMS';
            app.DownsampleSwitch = uiswitch(app.ShowLFPUIFigure, 'slider');
            app.DownsampleSwitch.Value = 'On';
            app.NormalizeSwitch = uiswitch(app.ShowLFPUIFigure, 'slider');
            app.NormalizeSwitch.Value = 'On';
            % Show the figure after all components are created
            app.ShowLFPUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DialogShowLFP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShowLFPUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShowLFPUIFigure)
        end
    end
end